classdef masterEquationSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MasterEquationsUIFigure         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        tunnelcapacitorC1FEditFieldLabel  matlab.ui.control.Label
        tunnelcapacitorC1FEditField     matlab.ui.control.NumericEditField
        tunnelcapacitorC2FEditFieldLabel  matlab.ui.control.Label
        tunnelcapacitorC2FEditField     matlab.ui.control.NumericEditField
        gatecapacitorCgFEditFieldLabel  matlab.ui.control.Label
        gatecapacitorCgFEditField       matlab.ui.control.NumericEditField
        tunnelresistanceR1MegaOhmEditFieldLabel  matlab.ui.control.Label
        tunnelresistanceR1MegaOhmEditField  matlab.ui.control.NumericEditField
        tunnelresistanceR2MegaOhmEditFieldLabel  matlab.ui.control.Label
        tunnelresistanceR2MegaOhmEditField  matlab.ui.control.NumericEditField
        gatevoltageVgEditFieldLabel     matlab.ui.control.Label
        gatevoltageVgEditField          matlab.ui.control.NumericEditField
        backgroundchargeq0isassumedtobezeroLabel  matlab.ui.control.Label
        backgroundchargeq0isassumedtobezeroEditField  matlab.ui.control.NumericEditField
        temperatureTKEditFieldLabel     matlab.ui.control.Label
        temperatureTKEditField          matlab.ui.control.NumericEditField
        drainvoltageminimumVminVEditFieldLabel  matlab.ui.control.Label
        drainvoltageminimumVminVEditField  matlab.ui.control.NumericEditField
        drainvoltagemaximumVmaxVEditFieldLabel  matlab.ui.control.Label
        drainvoltagemaximumVmaxVEditField  matlab.ui.control.NumericEditField
        minimumnumberofNchargenumberindotEditFieldLabel  matlab.ui.control.Label
        minimumnumberofNchargenumberindotEditField  matlab.ui.control.NumericEditField
        maximumnumberofNchargenumberindotEditFieldLabel  matlab.ui.control.Label
        maximumnumberofNchargenumberindotEditField  matlab.ui.control.NumericEditField
        PLOTButton                      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        PrincipalInterestUIAxes         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            % Matlab program source for numerical simulation of Master equation
            % in single electron transistor
            
            %1. first step, the following physical constant and device parameters are defined as follows.
            %clear all;
            % Definition of Physical constant
            q=1.602e-19;    % electronic charge (C)
            kb=1.381e-23;   % Boltzman constant (J/K)
            % Definition of Device parameters
            c1=app.tunnelcapacitorC1FEditField.Value;     % tunnel capacitor C1 (F)
            c2=app.tunnelcapacitorC2FEditField.Value;     % tunnel capacitor C2 (F)
            cg=app.gatecapacitorCgFEditField.Value;     % gate capacitor Cg (F)
            ctotal=c1+c2+cg;    % total capacitance (F)
            mega=1000000;   % definition of mega=106
            r1=app.tunnelresistanceR1MegaOhmEditField.Value*mega;     % tunnel resistance R1 (Ohm)
            r2=app.tunnelresistanceR2MegaOhmEditField.Value*mega;    % tunnel resistance R2 (Ohm)
            
            %2. The values of external parameters (V, V_G, Q_0 and T) is given. Here,
            %the V_G , Q_0 and T are kept a constant while the V is varied from Vmin to Vmax, as follows:
            Vg=app.gatevoltageVgEditField.Value;   % gate voltage (V)
            q0=app.backgroundchargeq0isassumedtobezeroEditField.Value;   % background charge q0 is assumed to be zero
            temp=app.temperatureTKEditField.Value;    % temperature T (K)
            vmin=app.drainvoltageminimumVminVEditField.Value;  % drain voltage minimum Vmin (V)
            vmax=app.drainvoltagemaximumVmaxVEditField.Value;   % drain voltage maximum Vmax (V)
            NV=1000;    % number of grid from Vmin to Vmax
            dV=(vmax-vmin)/NV;  % drain voltage increment of each grid point
            for iv=1:NV     % loop start for drain voltage
                V(iv)=vmin+iv*dV;   % drain voltage in each grid point
                % Note that loop end for drain voltage is located in the end of this program source
                
                %3. Calculation of ?F, as follows:
                Nmin=app.minimumnumberofNchargenumberindotEditField.Value;    % minimum number of N (charge number in dot)
                Nmax=app.maximumnumberofNchargenumberindotEditField.Value;     % maximum number of N (charge number in dot)
                for ne=1:Nmax-Nmin  % loop start for N
                    n=Nmin+ne;   % N charge number in dot
                    %Calculation of ?? in equations (25a) and (25b)
                    dF1p=q/ctotal*(0.5*q+(n*q-q0)-(c2+cg)*V(iv)+cg*Vg);
                    dF1n=q/ctotal*(0.5*q-(n*q-q0)+(c2+cg)*V(iv)-cg*Vg);
                    dF2p=q/ctotal*(0.5*q-(n*q-q0)-c1*V(iv)-cg*Vg);
                    dF2n=q/ctotal*(0.5*q+(n*q-q0)+c1*V(iv)+cg*Vg);
                    % Noted that loop end for N is located after calculation of \Gamma
                    
                    %4. the values of ?F are identified and then used for the calculation of \Gamma. If ?F is negative,
                    % will be calculated by equations (26a) and (26b). However, if the ?F is positive, \Gamma is set to
                    % be closed to the zero (very small). Note that the value of \Gamm ais always positive. These
                    % identifications are done for four conditiond of ?F.
                    
                    if dF1p<0
                        T1p(ne)=1/(r1*q*q)*(-dF1p)/(1-exp(dF1p/(kb*temp)));
                        % ? positive in equation (26a)
                    else
                        T1p(ne)=1e-1; % ? positive is assumed to be very small
                    end
                    if dF1n<0
                        T1n(ne)=1/(r1*q*q)*(-dF1n)/(1-exp(dF1n/(kb*temp)));
                        % ? negative in equation (26a)
                    else
                        T1n(ne)=1e-1; % ? negative is assumed to be very small
                    end
                    if dF2p<0
                        T2p(ne)=1/(r2*q*q)*(-dF2p)/(1-exp(dF2p/(kb*temp)));
                        % ? positive in equation (26b)
                    else
                        T2p(ne)=1e-1; % ? positive is assumed to be very small
                    end
                    
                    if dF2n<0
                        T2n(ne)=1/(r2*q*q)*(-dF2n)/(1-exp(dF2n/(kb*temp)));
                        % ? negative in equation (26b)
                    else
                        T2n(ne)=1e-1; % ? negative is assumed to be very small
                        
                    end
                end % loop end for N
                
                % 5. The p(n) of equation (28) is calculated. For this, normalization of equation (31a) must
                % be satisfied. Here, the values of p(Nmin) and p(Nmax) is assumed to be 0.01.
                
                p(1)=0.001; % ?(Nmin) is assumed to be 0.01
                p(Nmax-Nmin)=0.001; % ?(Nmax) is assumed to be 0.01
                % 6. Normalization of p is done. Here, ? p(n) is calculated.
                sum=0; % sum=0 is initial value to calculate ?
                for ne=2:Nmax-Nmin
                    p(ne)=p(ne-1)*(T2n(ne-1)+T1p(ne-1))/(T2p(ne)+T1n(ne));
                    % calculation of ?(N) in equation (28)
                    % The conditions below are used to avoid divergence of Matlab  calculation
                    
                    if p(ne)>1e250
                        p(ne)=1e250;
                    end
                    if p(ne)<1e-250
                        p(ne)=1e-250;
                    end
                    % ---------------------
                    sum=sum+p(ne);
                end
                for ne=2:Nmax-Nmin
                    p(ne)=p(ne)/sum; % Normalization in equation (31b)
                end
                
                %Finally, the current is computed as follows:
                
                sumI=0; % sumI=0 is initial condition for current calculation
                
                for ne=2:Nmax-Nmin
                    sumI=sumI+p(ne)*(T2p(ne)-T2n(ne));
                end
                I(iv)=q*sumI; % I in equation (32b)
            end % end of drain voltage loop
            app.PrincipalInterestUIAxes.Title.String='plot of I vs V';
            plot(app.PrincipalInterestUIAxes, V,I); % plot of I vs V
            xlabel (app.PrincipalInterestUIAxes, 'Drain voltage V');
            ylabel (app.PrincipalInterestUIAxes, 'Current I');
            hold(app.PrincipalInterestUIAxes, 'off');
            for iv=1:NV-1
                dIdV(iv)=(I(iv+1)-I(iv))/dV; % calculation of dI/dV
            end
            app.UIAxes.Title.String='plot of dI/dV vs V';
            plot(app.UIAxes, V(1,1:NV-1), dIdV);
            xlabel (app.UIAxes,'Drain voltage V');
            ylabel (app.UIAxes,'dI/dV');
            hold(app.UIAxes,'off');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MasterEquationsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {475, 475};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {413, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MasterEquationsUIFigure = uifigure('Visible', 'off');
            app.MasterEquationsUIFigure.Name = 'Master Equations Calculator';
            app.MasterEquationsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MasterEquationsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.tunnelcapacitorC1FEditFieldLabel = uilabel(app.LeftPanel);
            app.tunnelcapacitorC1FEditFieldLabel.Text = 'tunnel capacitor C1 (F)';
            app.tunnelcapacitorC1FEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tunnelcapacitorC1FEditField.Value = 1e-20;
            app.tunnelcapacitorC2FEditFieldLabel = uilabel(app.LeftPanel);
            app.tunnelcapacitorC2FEditFieldLabel.Text = 'tunnel capacitor C2 (F)';
            app.tunnelcapacitorC2FEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tunnelcapacitorC2FEditField.Value = 2e-19;
            app.gatecapacitorCgFEditFieldLabel = uilabel(app.LeftPanel);
            app.gatecapacitorCgFEditFieldLabel.Text = 'gate capacitor Cg (F)';
            app.gatecapacitorCgFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.gatecapacitorCgFEditField.Value = 1e-18;
            app.tunnelresistanceR1MegaOhmEditFieldLabel = uilabel(app.LeftPanel);
            app.tunnelresistanceR1MegaOhmEditFieldLabel.Text = 'tunnel resistance R1 (MegaOhm)';
            app.tunnelresistanceR1MegaOhmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tunnelresistanceR1MegaOhmEditField.Value = 15;
            app.tunnelresistanceR2MegaOhmEditFieldLabel = uilabel(app.LeftPanel);
            app.tunnelresistanceR2MegaOhmEditFieldLabel.Text = 'tunnel resistance R2 (MegaOhm)';
            app.tunnelresistanceR2MegaOhmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.tunnelresistanceR2MegaOhmEditField.Value = 250;
            app.gatevoltageVgEditFieldLabel = uilabel(app.LeftPanel);
            app.gatevoltageVgEditFieldLabel.Text = 'gate voltage (Vg)';
            app.gatevoltageVgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.backgroundchargeq0isassumedtobezeroLabel = uilabel(app.LeftPanel);
            app.backgroundchargeq0isassumedtobezeroLabel.Text = 'background charge q0 is assumed to be zero';
            app.backgroundchargeq0isassumedtobezeroEditField = uieditfield(app.LeftPanel, 'numeric');
            app.temperatureTKEditFieldLabel = uilabel(app.LeftPanel);
            app.temperatureTKEditFieldLabel.Text = 'temperature T (K)';
            app.temperatureTKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.temperatureTKEditField.Value = 10;
            app.drainvoltageminimumVminVEditFieldLabel = uilabel(app.LeftPanel);
            app.drainvoltageminimumVminVEditFieldLabel.Text = 'drain voltage minimum Vmin (V)';
            app.drainvoltageminimumVminVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.drainvoltageminimumVminVEditField.Value = -0.5;
            app.drainvoltagemaximumVmaxVEditFieldLabel = uilabel(app.LeftPanel);
            app.drainvoltagemaximumVmaxVEditFieldLabel.Text = 'drain voltage maximum Vmax (V)';
            app.drainvoltagemaximumVmaxVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.drainvoltagemaximumVmaxVEditField.Value = 0.5;
            app.minimumnumberofNchargenumberindotEditFieldLabel = uilabel(app.LeftPanel);
            app.minimumnumberofNchargenumberindotEditFieldLabel.Text = 'minimum number of N (charge number in dot)';
            app.minimumnumberofNchargenumberindotEditField = uieditfield(app.LeftPanel, 'numeric');
            app.minimumnumberofNchargenumberindotEditField.Value = -20;
            app.maximumnumberofNchargenumberindotEditFieldLabel = uilabel(app.LeftPanel);
            app.maximumnumberofNchargenumberindotEditFieldLabel.Text = 'maximum number of N (charge number in dot)';
            app.maximumnumberofNchargenumberindotEditField = uieditfield(app.LeftPanel, 'numeric');
            app.maximumnumberofNchargenumberindotEditField.Value = 20;
            app.PLOTButton = uibutton(app.LeftPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'plot of I vs V')
            xlabel(app.PrincipalInterestUIAxes, 'Drain voltage V')
            ylabel(app.PrincipalInterestUIAxes, 'Current I')
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'plot of dI/dV vs V')
            xlabel(app.UIAxes, 'Drain voltage V')
            ylabel(app.UIAxes, 'dI/dV')
            % Show the figure after all components are created
            app.MasterEquationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = masterEquationSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MasterEquationsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MasterEquationsUIFigure)
        end
    end
end