classdef montecarlovsmasterequation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Gamma                         matlab.ui.control.Button
        Plot                          matlab.ui.control.UIAxes
        maximumnumberofelectronscontainedindotnLabel  matlab.ui.control.Label
        n                             matlab.ui.control.Spinner
        GatevoltageVgEditFieldLabel   matlab.ui.control.Label
        GatevoltageVgEditField        matlab.ui.control.NumericEditField
        DrainVoltageVdEditFieldLabel  matlab.ui.control.Label
        DrainVoltageVdEditField       matlab.ui.control.NumericEditField
        GammapVd                      matlab.ui.control.Button
        GammapVg                      matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function y=master_equation_app(app, V,Vg,N)
            
            clear ma;
            for i=-N:1:N
                ma(N+i+1,N+i+2)=-(f_tunnel(1,i,V,Vg)+f_tunnel(2,i,V,Vg)+f_tunnel(3,i,V,Vg)+f_tunnel(4,i,V,Vg));
                ma(N+i+1,N+i+2-1)=f_tunnel(1,i-1,V,Vg)+f_tunnel(4,i-1,V,Vg); ma(N+i+1,N+i+2+1)=f_tunnel(2,i+1,V,Vg)+f_tunnel(3,i+1,V,Vg);
            end;
            mma=[1,zeros(1,2*N+2);ma;zeros(1,2*N+2),1];
            [p,o]=eig(mma);
            [s,posiz]=min(abs(diag(o)));
            p_n(:,1)=abs(p(:,posiz))/sum(abs(p(:,posiz)));
            for i=1:length(p_n)
                contr(i)=p_n(i)*(f_tunnel(1,i-N-2,V,Vg)-f_tunnel(2,i-N-2,V,Vg));
            end;
            plot(app.Plot, contr);
            hold(app.Plot, 'on')
            y=sum(contr);
        end;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Gamma
        function GammaPushed(app, event)
            clf(app.Plot);
            y = [];
            n = app.n.Value;
            for i = -n : n
                y = [y; f_tunnel0(1,i,app.DrainVoltageVdEditField.Value,app.GatevoltageVgEditField.Value)];
            end;
            %%% plot dei 4 tipi di gamma(n) per n che va da -10 a 10
            app.Plot.Title.String="\Gamma";
            plot(app.Plot,-n:n, y(:,1), -n:n, y(:,2), -n:n, y(:,3), -n:n, y(:,4));
            xlabel (app.Plot,'Numero di cariche nel dot (n)');
            ylabel (app.Plot,'Rate di tunneling (\Gamma_n)');
            legend (app.Plot,'\Gamma_{source->dot}', '\Gamma_{dot->source}', '\Gamma_{dot->drain}', '\Gamma_{drain->dot}')
        end

        % Button pushed function: GammapVd
        function GammapVdButtonPushed(app, event)
            clf(app.Plot);
            Vd=app.DrainVoltageVdEditField.Value;
            Vg=app.GatevoltageVgEditField.Value;
            N=app.n.Value;
            app.Plot.Title.String='plot del termine [p(n)*(\Gamma_{dot->R})(N) - (\Gamma_{R->dot})(N)] al variare di Vd';
            for i = 1:50
                corrente(i) = app.master_equation_app(exp(0.04*i)-1, Vg, N);
            end;
            xlabel (app.Plot, 'Tensione di drain (V)');
            ylabel (app.Plot, 'p(n)*[(\Gamma_{dot->R})(N) - (\Gamma_{R->dot})(N)]');
            %legend (num2str(exp(0.04*[1:50])));
        end

        % Button pushed function: GammapVg
        function GammapVgButtonPushed(app, event)
            clf(app.Plot);
            Vd=app.DrainVoltageVdEditField.Value;
            Vg=app.GatevoltageVgEditField.Value;
            N=app.n.Value;
            for i=1:50
                corrente(i)=app.master_equation_app(Vd, exp(0.04*i)-1, N);
            end;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Gamma = uibutton(app.UIFigure, 'push');
            app.Gamma.ButtonPushedFcn = createCallbackFcn(app, @GammaPushed, true);
            app.Gamma.Text = 'Plot Γ';
            app.Plot = uiaxes(app.UIFigure);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            app.maximumnumberofelectronscontainedindotnLabel = uilabel(app.UIFigure);
            app.maximumnumberofelectronscontainedindotnLabel.Text = 'maximum number of electrons contained in dot (n)';
            app.n = uispinner(app.UIFigure);
            app.n.Value = 10;
            app.GatevoltageVgEditFieldLabel = uilabel(app.UIFigure);
            app.GatevoltageVgEditFieldLabel.Text = 'Gate voltage (Vg)';
            app.GatevoltageVgEditField = uieditfield(app.UIFigure, 'numeric');
            app.GatevoltageVgEditField.Value = 10;
            app.DrainVoltageVdEditFieldLabel = uilabel(app.UIFigure);
            app.DrainVoltageVdEditFieldLabel.Text = 'Drain Voltage (Vd)';
            app.DrainVoltageVdEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammapVd = uibutton(app.UIFigure, 'push');
            app.GammapVd.ButtonPushedFcn = createCallbackFcn(app, @GammapVdButtonPushed, true);
            app.GammapVd.Text = 'Plot Γ*p(n) in relation of Vd';
            app.GammapVg = uibutton(app.UIFigure, 'push');
            app.GammapVg.ButtonPushedFcn = createCallbackFcn(app, @GammapVgButtonPushed, true);
            app.GammapVg.Text = 'Plot Γ*p(n) in relation of Vg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = montecarlovsmasterequation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end